classdef appLinearInterpolation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayoutLeft                  matlab.ui.container.GridLayout
        AKRInterpolationeinerRRKinematikLabel  matlab.ui.control.Label
        robotSimUIAxes                  matlab.ui.control.UIAxes
        angleUIAxes                     matlab.ui.control.UIAxes
        velUIAxes                       matlab.ui.control.UIAxes
        accUIAxes                       matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        ParameterzuniedriegLabel        matlab.ui.control.Label
        AutorenTimKeicherHeikoWascheckLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SystemStateLamp                 matlab.ui.control.Lamp
        SystemwirdausgefuehrtLampLabel  matlab.ui.control.Label
        AnhaltenButton                  matlab.ui.control.Button
        GiferzeugenCheckBox             matlab.ui.control.CheckBox
        ZielZeitsSpinner                matlab.ui.control.Spinner
        ZielZeitsSpinnerLabel           matlab.ui.control.Label
        BeschlVerlaufCheckBox           matlab.ui.control.CheckBox
        GeschwVerlaufCheckBox           matlab.ui.control.CheckBox
        WinkelVerlaufCheckBox           matlab.ui.control.CheckBox
        BewegungssimulationCheckBox     matlab.ui.control.CheckBox
        ResetButton                     matlab.ui.control.Button
        TargetTimeCheckBox              matlab.ui.control.CheckBox
        Beschlms2Spinner                matlab.ui.control.Spinner
        Beschlms2SpinnerLabel           matlab.ui.control.Label
        GeschwmsSpinner                 matlab.ui.control.Spinner
        GeschwmsSpinnerLabel            matlab.ui.control.Label
        ParamLamp                       matlab.ui.control.Lamp
        rConfigCheckBox                 matlab.ui.control.CheckBox
        StartButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        pause_flag = 0;             % Zum Anhalten und weiterfuehren der Darstellungen
        canle_old_runs_flag = 0;    % Flag um angehaltene Interrupts zu beenden
    end

    methods (Access = private)
        
        function resetAxes(app)
            % Alle Axen zurücksetzten
            cla(app.robotSimUIAxes);
            cla(app.angleUIAxes);
            cla(app.velUIAxes);
            cla(app.accUIAxes);

            % Loeschen der Legenden
            legend(app.robotSimUIAxes, 'off');
            legend(app.angleUIAxes, 'off');
            legend(app.velUIAxes, 'off');
            legend(app.accUIAxes, 'off');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Blockieren der checkboxen um ein wechsel waehrend des Durchlaufes zu verhindern
            app.BewegungssimulationCheckBox.Enable = 'off';
            app.WinkelVerlaufCheckBox.Enable = 'off';
            app.GeschwVerlaufCheckBox.Enable = 'off';
            app.BeschlVerlaufCheckBox.Enable = 'off';

            resetAxes(app);

            designAxes(app);

            % Gesetzte Geschwindigkeit und Beschleunigung auslesen
            vm = [app.GeschwmsSpinner.Value app.GeschwmsSpinner.Value]';
            am = [app.Beschlms2Spinner.Value app.Beschlms2Spinner.Value]';

            % Zielzeitfahrt bestimmen
            if(app.TargetTimeCheckBox.Value)
                t_target = app.ZielZeitsSpinner.Value;
            else
                t_target = 0;
            end

            % Signallampe wird gesetzt
            app.SystemStateLamp.Color = 'green';
            drawnow()

            % Berechnung und Darstellung der Bewegungsablaeuf            
            run_project(app, app.robotSimUIAxes, app.angleUIAxes, app.velUIAxes, app.accUIAxes, ...
                        app.rConfigCheckBox.Value, vm, am, t_target, app.GiferzeugenCheckBox);

            % Signallampe wird zurueckgesetzt
            app.SystemStateLamp.Color = [0.90,0.90,0.90];

            % Blockierung aufheben
            app.BewegungssimulationCheckBox.Enable = 'on';
            app.WinkelVerlaufCheckBox.Enable = 'on';
            app.GeschwVerlaufCheckBox.Enable = 'on';
            app.BeschlVerlaufCheckBox.Enable = 'on';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Abbrechen der Aktuellen Simulation
            app.pause_flag = 1;
            app.canle_old_runs_flag = 1;

            % Zuruecksetzten der Signallampe
            app.SystemStateLamp.Color = [0.90,0.90,0.90];
            
            resetAxes(app);

            % Anhalte Button zuruecksetzten
            app.pause_flag = 0;
            app.AnhaltenButton.Text = 'Anhalten';
        end

        % Button pushed function: AnhaltenButton
        function AnhaltenButtonPushed(app, event)
            
            if app.pause_flag == 0
                app.pause_flag = 1;
                app.AnhaltenButton.Text = 'Abspielen';
            else
                app.pause_flag = 0;
                app.AnhaltenButton.Text = 'Anhalten';
            end
        end

        % Value changed function: TargetTimeCheckBox
        function TargetTimeCheckBoxValueChanged(app, event)
            if app.TargetTimeCheckBox.Value
                app.ZielZeitsSpinner.Enable = 'on';
            else
                app.ZielZeitsSpinner.Enable = 'off';
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {661, 661};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {834, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutLeft = uigridlayout(app.LeftPanel);
            app.accUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.accUIAxes, 'Beschleunigungsverlauf')
            xlabel(app.accUIAxes, 'Zeit [s]')
            ylabel(app.accUIAxes, 'Beschleunigung [m/s^2]')
            zlabel(app.accUIAxes, 'Z')
            app.velUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.velUIAxes, 'Geschwindigkeitsverlauf')
            xlabel(app.velUIAxes, 'Zeit [s]')
            ylabel(app.velUIAxes, 'Geschwindigkeit [m/s]')
            zlabel(app.velUIAxes, 'Z')
            app.angleUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.angleUIAxes, 'Winkelverlauf J1 / J2')
            xlabel(app.angleUIAxes, 'Zeit [s]')
            ylabel(app.angleUIAxes, 'Winkel [°]')
            zlabel(app.angleUIAxes, 'Z')
            app.robotSimUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.robotSimUIAxes, 'Bewegungsanimation')
            xlabel(app.robotSimUIAxes, 'Strecke [m]')
            ylabel(app.robotSimUIAxes, 'Strecke [m]')
            zlabel(app.robotSimUIAxes, 'Z')
            app.AKRInterpolationeinerRRKinematikLabel = uilabel(app.GridLayoutLeft);
            app.AKRInterpolationeinerRRKinematikLabel.Text = 'AKR: Interpolation einer RR-Kinematik';
            app.RightPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.rConfigCheckBox = uicheckbox(app.RightPanel);
            app.rConfigCheckBox.Text = 'Konfig umgekehrt';
            app.ParamLamp = uilamp(app.RightPanel);
            app.GeschwmsSpinnerLabel = uilabel(app.RightPanel);
            app.GeschwmsSpinnerLabel.Text = 'Geschw. [m/s]';
            app.GeschwmsSpinner = uispinner(app.RightPanel);
            app.GeschwmsSpinner.Step = 0.1;
            app.GeschwmsSpinner.Limits = [0 Inf];
            app.GeschwmsSpinner.Value = 3;
            app.Beschlms2SpinnerLabel = uilabel(app.RightPanel);
            app.Beschlms2SpinnerLabel.Text = 'Beschl. [m/s^2]';
            app.Beschlms2Spinner = uispinner(app.RightPanel);
            app.Beschlms2Spinner.Step = 0.1;
            app.Beschlms2Spinner.Limits = [0 Inf];
            app.Beschlms2Spinner.Value = 0.2;
            app.TargetTimeCheckBox = uicheckbox(app.RightPanel);
            app.TargetTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TargetTimeCheckBoxValueChanged, true);
            app.TargetTimeCheckBox.Text = 'Zeitfahrt';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BewegungssimulationCheckBox = uicheckbox(app.RightPanel);
            app.BewegungssimulationCheckBox.Text = 'Bewegungssimulation';
            app.BewegungssimulationCheckBox.Value = true;
            app.WinkelVerlaufCheckBox = uicheckbox(app.RightPanel);
            app.WinkelVerlaufCheckBox.Text = 'Winkel Verlauf';
            app.WinkelVerlaufCheckBox.Value = true;
            app.GeschwVerlaufCheckBox = uicheckbox(app.RightPanel);
            app.GeschwVerlaufCheckBox.Text = 'Geschw. Verlauf';
            app.GeschwVerlaufCheckBox.Value = true;
            app.BeschlVerlaufCheckBox = uicheckbox(app.RightPanel);
            app.BeschlVerlaufCheckBox.Text = 'Beschl. Verlauf';
            app.BeschlVerlaufCheckBox.Value = true;
            app.ZielZeitsSpinnerLabel = uilabel(app.RightPanel);
            app.ZielZeitsSpinnerLabel.Text = 'Ziel Zeit [s]';
            app.ZielZeitsSpinner = uispinner(app.RightPanel);
            app.ZielZeitsSpinner.Limits = [0 Inf];
            app.ZielZeitsSpinner.Enable = 'off';
            app.GiferzeugenCheckBox = uicheckbox(app.RightPanel);
            app.GiferzeugenCheckBox.Text = 'Gif erzeugen';
            app.AnhaltenButton = uibutton(app.RightPanel, 'push');
            app.AnhaltenButton.ButtonPushedFcn = createCallbackFcn(app, @AnhaltenButtonPushed, true);
            app.AnhaltenButton.Text = 'Anhalten';
            app.SystemwirdausgefuehrtLampLabel = uilabel(app.RightPanel);
            app.SystemwirdausgefuehrtLampLabel.Text = 'System wird ausgefuehrt';
            app.SystemStateLamp = uilamp(app.RightPanel);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, '70f3d6f3daf5b559-83a5ca4c96b0-HHN_Logo_D_RGB_300.png');
            app.AutorenTimKeicherHeikoWascheckLabel = uilabel(app.RightPanel);
            app.AutorenTimKeicherHeikoWascheckLabel.Text = {'Autoren:'; 'Tim Keicher, Heiko Wascheck'};
            app.ParameterzuniedriegLabel = uilabel(app.RightPanel);
            app.ParameterzuniedriegLabel.Text = 'Parameter zu niedrieg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appLinearInterpolation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end