classdef appTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayoutLeft               matlab.ui.container.GridLayout
        robotSimUIAxes               matlab.ui.control.UIAxes
        angleUIAxes                  matlab.ui.control.UIAxes
        velUIAxes                    matlab.ui.control.UIAxes
        accUIAxes                    matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        StopButton                   matlab.ui.control.Button
        GifErzeugenCheckBox          matlab.ui.control.CheckBox
        ZielZeitSpinner              matlab.ui.control.Spinner
        ZielZeitSpinnerLabel         matlab.ui.control.Label
        BeschlVerlaufCheckBox        matlab.ui.control.CheckBox
        GeschwVerlaufCheckBox        matlab.ui.control.CheckBox
        WinkelVerlaufCheckBox        matlab.ui.control.CheckBox
        BewegungssimulationCheckBox  matlab.ui.control.CheckBox
        ResetButton                  matlab.ui.control.Button
        TargetTimeCheckBox           matlab.ui.control.CheckBox
        AccSigLamp                   matlab.ui.control.Lamp
        BeschlSpinner                matlab.ui.control.Spinner
        BeschlSpinnerLabel           matlab.ui.control.Label
        GeschwSpinner                matlab.ui.control.Spinner
        GeschwSpinnerLabel           matlab.ui.control.Label
        VelSigLamp                   matlab.ui.control.Lamp
        rConfigCheckBox              matlab.ui.control.CheckBox
        StartButton                  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % test(app.angleUIAxes, app.rConfigCheckBox.Value);
            % disp(app.rConfigCheckBox.Value);

            visibleAxes = getVisibleAxes(app);

            % Setzen der Sichtbarkeit für alle Axes
            app.robotSimUIAxes.Visible = visibleAxes(1);
            app.angleUIAxes.Visible = visibleAxes(2);
            app.velUIAxes.Visible = visibleAxes(3);
            app.accUIAxes.Visible = visibleAxes(4);


            % c1 = '0x';
            % c2 = '0x';
            % r1 = '0x';
            % r2 = '0x';
            cnt = 1;

            if visibleAxes(1)
                app.robotSimUIAxes.Layout.Row = 1;
                app.robotSimUIAxes.Layout.Column = 1;
                cnt = cnt + 1;
                % c1 = '1x';
                % r1 = '1x';
            end

            if visibleAxes(2)
                app.angleUIAxes.Layout.Row = 1;
                app.angleUIAxes.Layout.Column = cnt;
                cnt = cnt + 1;
                % r1 = '1x';
                % c2 = '1x';
            end

            if visibleAxes(3)
                if mod(cnt,2) == 0
                    app.velUIAxes.Layout.Row = cnt / 2;
                    app.velUIAxes.Layout.Column = 2;
                else
                    app.velUIAxes.Layout.Row = round(cnt/2);
                    app.velUIAxes.Layout.Column = 1;
                end
                cnt = cnt + 1;
                % r2 = '1x';
                % c1 = '1x';
            end

            if visibleAxes(4)
                if mod(cnt,2) == 0
                    app.accUIAxes.Layout.Row = cnt / 2;
                    app.accUIAxes.Layout.Column = 2;
                else
                    app.accUIAxes.Layout.Row = round(cnt/2);
                    app.accUIAxes.Layout.Column = 1;
                end
                % r2 = '1x';
                % c2 = '1x';
            end

            % app.GridLayoutLeft.RowHeight = {r1, r2};
            % app.GridLayoutLeft.ColumnWidth = {c1, c2};

            % app.robotSimUIAxes.Layout.Row = 1;
            % app.robotSimUIAxes.Layout.Column = 1;

            switch sum(visibleAxes)
                case 1
                    % Eine sichtbare Axes: Ganzes Layout verwenden
                    app.GridLayoutLeft.RowHeight = {'1x', '0x'};
                    app.GridLayoutLeft.ColumnWidth = {'1x', '0x'};
                case 2
                    % Zwei sichtbare Axes: 2x1 Layout
                    app.GridLayoutLeft.RowHeight = {'1x', '0x'};
                    app.GridLayoutLeft.ColumnWidth = {'1x', '1x'};
                case 3
                    % Drei sichtbare Axes: 2x2 Layout
                    app.GridLayoutLeft.RowHeight = {'1x', '1x'};
                    app.GridLayoutLeft.ColumnWidth = {'1x', '1x'};
                case 4
                    % Vier sichtbare Axes: 2x2 Layout
                    app.GridLayoutLeft.RowHeight = {'1x', '1x'};
                    app.GridLayoutLeft.ColumnWidth = {'1x', '1x'};
                otherwise
                    disp("case otherwise");
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.robotSimUIAxes);
            cla(app.angleUIAxes);
            cla(app.velUIAxes);
            cla(app.accUIAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {498, 498};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {686, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutLeft = uigridlayout(app.LeftPanel);
            app.accUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.accUIAxes, 'Beschleunigungsverlauf')
            xlabel(app.accUIAxes, 'Zeit [t]')
            ylabel(app.accUIAxes, 'Beschleunigung [m/s^2]')
            zlabel(app.accUIAxes, 'Z')
            app.velUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.velUIAxes, 'Geschwindigkeitsverlauf')
            xlabel(app.velUIAxes, 'Zeit [t]')
            ylabel(app.velUIAxes, 'Geschwindigkeit [m/s]')
            zlabel(app.velUIAxes, 'Z')
            app.angleUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.angleUIAxes, 'Winkelverlauf J1 / J2')
            xlabel(app.angleUIAxes, 'Zeit [t]')
            ylabel(app.angleUIAxes, 'Winkel [°]')
            zlabel(app.angleUIAxes, 'Z')
            app.robotSimUIAxes = uiaxes(app.GridLayoutLeft);
            title(app.robotSimUIAxes, 'Bewegungsanimation')
            xlabel(app.robotSimUIAxes, 'Strecke [m]')
            ylabel(app.robotSimUIAxes, 'Strecke [m]')
            zlabel(app.robotSimUIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.rConfigCheckBox = uicheckbox(app.RightPanel);
            app.rConfigCheckBox.Text = 'Konfig Umgekehrt';
            app.VelSigLamp = uilamp(app.RightPanel);
            app.GeschwSpinnerLabel = uilabel(app.RightPanel);
            app.GeschwSpinnerLabel.Text = 'Geschw.';
            app.GeschwSpinner = uispinner(app.RightPanel);
            app.GeschwSpinner.Step = 0.1;
            app.GeschwSpinner.Limits = [0 Inf];
            app.GeschwSpinner.Value = 3;
            app.BeschlSpinnerLabel = uilabel(app.RightPanel);
            app.BeschlSpinnerLabel.Text = 'Beschl.';
            app.BeschlSpinner = uispinner(app.RightPanel);
            app.BeschlSpinner.Step = 0.1;
            app.BeschlSpinner.Limits = [0 Inf];
            app.BeschlSpinner.Value = 0.2;
            app.AccSigLamp = uilamp(app.RightPanel);
            app.TargetTimeCheckBox = uicheckbox(app.RightPanel);
            app.TargetTimeCheckBox.Text = 'Zeitfahrt';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BewegungssimulationCheckBox = uicheckbox(app.RightPanel);
            app.BewegungssimulationCheckBox.Text = 'Bewegungssimulation';
            app.BewegungssimulationCheckBox.Value = true;
            app.WinkelVerlaufCheckBox = uicheckbox(app.RightPanel);
            app.WinkelVerlaufCheckBox.Text = 'Winkel Verlauf';
            app.WinkelVerlaufCheckBox.Value = true;
            app.GeschwVerlaufCheckBox = uicheckbox(app.RightPanel);
            app.GeschwVerlaufCheckBox.Text = 'Geschw. Verlauf';
            app.GeschwVerlaufCheckBox.Value = true;
            app.BeschlVerlaufCheckBox = uicheckbox(app.RightPanel);
            app.BeschlVerlaufCheckBox.Text = 'Beschl. Verlauf';
            app.BeschlVerlaufCheckBox.Value = true;
            app.ZielZeitSpinnerLabel = uilabel(app.RightPanel);
            app.ZielZeitSpinnerLabel.Text = 'Ziel Zeit';
            app.ZielZeitSpinner = uispinner(app.RightPanel);
            app.ZielZeitSpinner.Limits = [0 Inf];
            app.GifErzeugenCheckBox = uicheckbox(app.RightPanel);
            app.GifErzeugenCheckBox.Text = 'Gif Erzeugen';
            app.StopButton = uibutton(app.RightPanel, 'push');
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end